classdef lst < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        AddLineButton  matlab.ui.control.Button
        DropDown_6     matlab.ui.control.DropDown
        Label_6        matlab.ui.control.Label
        DropDown_5     matlab.ui.control.DropDown
        Label_5        matlab.ui.control.Label
        DropDown_4     matlab.ui.control.DropDown
        Label_4        matlab.ui.control.Label
        DropDown_3     matlab.ui.control.DropDown
        Label_3        matlab.ui.control.Label
        DropDown_2     matlab.ui.control.DropDown
        Label_2        matlab.ui.control.Label
        DropDown       matlab.ui.control.DropDown
        Label          matlab.ui.control.Label
        CheckBox_2     matlab.ui.control.CheckBox
        CheckBox       matlab.ui.control.CheckBox
        Button_4       matlab.ui.control.Button
        AddListButton  matlab.ui.control.Button
        UITable2       matlab.ui.control.Table
        CancelButton   matlab.ui.control.Button
        OKButton       matlab.ui.control.Button
        UITable        matlab.ui.control.Table
    end

    
    properties (Access = private)
        fname
        np
        l_tbl
        select_row
        select_name
        CallingApp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % メインアプリから変数を取得
            app.CallingApp = mainapp;
            path = mainapp.path;
            fname = mainapp.fname;
            np = mainapp.np;
            filename = [path fname '.xlsx'];
            result = isfile(filename);
            p_tbl = table();
            if result == 1
                tmp_tbl = readtable(filename,'Sheet','point');
                p_tbl.point = tmp_tbl.point;
                p_tbl.name = tmp_tbl.name;
                p_tbl.scatter = logical(tmp_tbl.scatter);
                tmp_tbl = readtable(filename,'Sheet','line');
                l_tbl = table();
                l_tbl.list = tmp_tbl.list;
                l_tbl.line = tmp_tbl.line;
                l_tbl.type = tmp_tbl.type;
                l_tbl.color = tmp_tbl.color;
                app.UITable2.Data = l_tbl;
            else
                for ipnt = 1:np
                    p_tbl.point(ipnt) = ipnt;
                    p_tbl.name(ipnt) = ["P" + string(ipnt)];
                    p_tbl.scatter(ipnt) = true;
                end
            end

            app.UITable.Data = p_tbl;
            app.fname = fname;
            app.np = np;
            app.l_tbl = table();
        end

        % Button pushed function: AddListButton
        function AddListButtonPushed(app, event)
            np = app.np;
            p_tbl = app.UITable.DisplayData;
            %pname = p_tbl.name;
            l_tbl = app.l_tbl;
            n = height(l_tbl);
            i = n + 1;
            l_tbl.list(i) = ["Line" + string(i)];
            l_tbl.line(i) = "";
            
            line_type = categorical("実線",{'実線','破線','点線','一点鎖線'});
            l_tbl.type(i) = line_type;
            
            line_color = categorical("白",{'白','赤','緑','青','黄','マゼンタ','シアン','黒'});
            l_tbl.color(i) = line_color;
            
            app.UITable2.Data = l_tbl;
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            select_row = indices(1);
            app.select_row = select_row;
            p_tbl = app.UITable.DisplayData;
            pname = p_tbl.name;
            select_name = pname(select_row);
            app.select_name = select_name;
        end

        % Button pushed function: AddLineButton
        function AddLineButtonPushed(app, event)
            select_name = app.select_name;
            tmp_name = app.UITable2.Data.line;
            if tmp_name == ""
                add_name = [select_name];
            else
                add_name = [tmp_name+"-"+select_name];
            end
            app.UITable2.Data.line = add_name;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            p_tbl = app.UITable.DisplayData;
            l_tbl = app.UITable2.DisplayData;
            for ilist = 1:height(l_tbl)
                tmp_ln = l_tbl.line(ilist);
                dat_split = split(tmp_ln,'-');
                n_split = length(dat_split);
                for i_split = 1:n_split
                    p = string(dat_split(i_split));
                    pname = p_tbl.name;
                    for irow = 1:height(p_tbl)
                        tmp_name = string(pname(irow));
                        if p == tmp_name
                            point_no = irow;
                            break;
                        else
                            dummy=1;
                        end
                    end
                    p_id(i_split) = irow;
                end
                ln(ilist).p_id = p_id;
            end            
            app.CallingApp.ln = ln;
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'ﾎﾟｲﾝﾄ'; '名称'; '表示'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true true];
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.Text = 'Cancel';
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'結線リスト'; '結線'; '種類'; '色'};
            app.UITable2.RowName = {};
            app.UITable2.ColumnEditable = [false true true true];
            app.AddListButton = uibutton(app.UIFigure, 'push');
            app.AddListButton.ButtonPushedFcn = createCallbackFcn(app, @AddListButtonPushed, true);
            app.AddListButton.Text = 'リストを追加';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.Text = '削除';
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.Text = '点';
            app.CheckBox_2 = uicheckbox(app.UIFigure);
            app.CheckBox_2.Text = '線';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '大きさ';
            app.DropDown = uidropdown(app.UIFigure);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '塗りつぶし';
            app.DropDown_2 = uidropdown(app.UIFigure);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '外枠の色';
            app.DropDown_3 = uidropdown(app.UIFigure);
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '種類';
            app.DropDown_4 = uidropdown(app.UIFigure);
            app.DropDown_4.Items = {'実線', '破線', '点線', '一点鎖線'};
            app.DropDown_4.Value = '実線';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '色';
            app.DropDown_5 = uidropdown(app.UIFigure);
            app.DropDown_5.Items = {'白', '黒', '赤', '緑', '青', '黄', 'マゼンタ', 'シアン'};
            app.DropDown_5.Value = '白';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '種類';
            app.DropDown_6 = uidropdown(app.UIFigure);
            app.AddLineButton = uibutton(app.UIFigure, 'push');
            app.AddLineButton.ButtonPushedFcn = createCallbackFcn(app, @AddLineButtonPushed, true);
            app.AddLineButton.Text = '結線に追加';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lst(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end